classdef app1 < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure                    matlab.ui.Figure
        RotationFactorSpinner       matlab.ui.control.Spinner
        RotationFactorSpinnerLabel  matlab.ui.control.Label
        IterationsSpinner           matlab.ui.control.Spinner
        GenerateButton              matlab.ui.control.Button
        IterationsSpinnerLabel      matlab.ui.control.Label
        ResolutionEditField         matlab.ui.control.NumericEditField
        ResolutionEditFieldLabel    matlab.ui.control.Label
        GraphTypeDropDown           matlab.ui.control.DropDown
        GraphTypeDropDownLabel      matlab.ui.control.Label
        FunctionEditField           matlab.ui.control.EditField
        FunctionEditFieldLabel      matlab.ui.control.Label
        UIAxes                      matlab.ui.control.UIAxes
    end

    
    properties (Access = private)
        d
        n
        GraphType
        f
        rotationFactor 
    end
    

    % Callbacks that handle component events
    methods (Access = private)

        % Value changed function: GraphTypeDropDown
        function GraphTypeDropDownValueChanged(app, event)
            app.GraphType = app.GraphTypeDropDown.Value;
            
        end

        % Button pushed function: GenerateButton
        function GenerateButtonPushed(app, event)
            syms x;
            p(x) = str2sym(app.f);
            [R,r,X] = NewtonsFractalAD(p, app.d, app.n, app.rotationFactor);
            if app.GraphTypeDropDown.Value == "image"
                imagesc(app.UIAxes, X(1,:),X(1,:),R);
            elseif app.GraphTypeDropDown.Value == "contour"
                contourf(app.UIAxes, X(1,:),X(1,:),R);
            end
            colormap(app.UIAxes, "parula");
            plot(app.UIAxes, r, LineStyle="none", Marker="pentagram", MarkerFaceColor="black", MarkerSize=10)
            
        end

        % Value changed function: ResolutionEditField
        function ResolutionEditFieldValueChanged(app, event)
            app.d = app.ResolutionEditField.Value;
            
        end

        % Callback function
        function NumberofIterationsEditFieldValueChanged(app, event)
            app.n = event.Value;
            GenerateButtonPushed(app)

            
        end

        % Value changed function: FunctionEditField
        function FunctionEditFieldValueChanged(app, event)
            app.f = app.FunctionEditField.Value;
            
        end

        % Button down function: UIAxes
        function UIAxesButtonDown(app, event)
            
        end

        % Value changed function: IterationsSpinner
        function IterationsSpinnerValueChanged(app, event)
            app.n = app.IterationsSpinner.Value;
            
        end

        % Callback function
        function RotationFactorSliderValueChanging(app, event)
            app.rotationFactor = event.Value;
            GenerateButtonPushed(app)
        end

        % Callback function
        function RotationFactorSliderValueChanged(app, event)
           app.rotationFactor = event.Value;
            
        end

        % Value changing function: IterationsSpinner
        function IterationsSpinnerValueChanging(app, event)
            app.n = event.Value;
            
            GenerateButtonPushed(app)
        end

        % Value changed function: RotationFactorSpinner
        function RotationFactorSpinnerValueChanged(app, event)
            app.rotationFactor = app.RotationFactorSpinner.Value;
            
        end

        % Value changing function: RotationFactorSpinner
        function RotationFactorSpinnerValueChanging(app, event)
            app.rotationFactor = event.Value;
            GenerateButtonPushed(app)

        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'MATLAB App';
            app.UIAxes = uiaxes(app.UIFigure);
            title(app.UIAxes, 'Newton''s Fractal Generator ')
            xlabel(app.UIAxes, 'Re')
            ylabel(app.UIAxes, 'Img')
            zlabel(app.UIAxes, 'Z')
            app.UIAxes.XLim = [-2 2];
            app.UIAxes.YLim = [-2 2];
            app.UIAxes.XTick = [-2 -1.5 -1 -0.5 0 0.5 1 1.5 2];
            app.UIAxes.YTick = [-2 -1.5 -1 -0.5 0 0.5 1 1.5 2];
            app.UIAxes.ButtonDownFcn = createCallbackFcn(app, @UIAxesButtonDown, true);
            app.FunctionEditFieldLabel = uilabel(app.UIFigure);
            app.FunctionEditFieldLabel.Text = 'Function';
            app.FunctionEditField = uieditfield(app.UIFigure, 'text');
            app.FunctionEditField.ValueChangedFcn = createCallbackFcn(app, @FunctionEditFieldValueChanged, true);
            app.GraphTypeDropDownLabel = uilabel(app.UIFigure);
            app.GraphTypeDropDownLabel.Text = 'Graph Type';
            app.GraphTypeDropDown = uidropdown(app.UIFigure);
            app.GraphTypeDropDown.Items = {'image', 'contour'};
            app.GraphTypeDropDown.ValueChangedFcn = createCallbackFcn(app, @GraphTypeDropDownValueChanged, true);
            app.GraphTypeDropDown.Value = 'image';
            app.ResolutionEditFieldLabel = uilabel(app.UIFigure);
            app.ResolutionEditFieldLabel.Text = 'Resolution';
            app.ResolutionEditField = uieditfield(app.UIFigure, 'numeric');
            app.ResolutionEditField.Limits = [0 5000];
            app.ResolutionEditField.ValueChangedFcn = createCallbackFcn(app, @ResolutionEditFieldValueChanged, true);
            app.IterationsSpinnerLabel = uilabel(app.UIFigure);
            app.IterationsSpinnerLabel.Text = 'Iterations';
            app.GenerateButton = uibutton(app.UIFigure, 'push');
            app.GenerateButton.ButtonPushedFcn = createCallbackFcn(app, @GenerateButtonPushed, true);
            app.GenerateButton.Text = 'Generate';
            app.IterationsSpinner = uispinner(app.UIFigure);
            app.IterationsSpinner.ValueChangingFcn = createCallbackFcn(app, @IterationsSpinnerValueChanging, true);
            app.IterationsSpinner.Limits = [0 20];
            app.IterationsSpinner.ValueChangedFcn = createCallbackFcn(app, @IterationsSpinnerValueChanged, true);
            app.RotationFactorSpinnerLabel = uilabel(app.UIFigure);
            app.RotationFactorSpinnerLabel.Text = {'Rotation'; 'Factor'};
            app.RotationFactorSpinner = uispinner(app.UIFigure);
            app.RotationFactorSpinner.ValueChangingFcn = createCallbackFcn(app, @RotationFactorSpinnerValueChanging, true);
            app.RotationFactorSpinner.Limits = [1 47];
            app.RotationFactorSpinner.RoundFractionalValues = 'on';
            app.RotationFactorSpinner.ValueChangedFcn = createCallbackFcn(app, @RotationFactorSpinnerValueChanged, true);
            app.RotationFactorSpinner.Value = 1;
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = app1

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end